classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FrequencyKnobLabel  matlab.ui.control.Label
        FrequencyKnob       matlab.ui.control.Knob
        UIAxes              matlab.ui.control.UIAxes
        AmplitudeKnobLabel  matlab.ui.control.Label
        AmplitudeKnob       matlab.ui.control.Knob
        PlotButton          matlab.ui.control.Button
        DropDownLabel       matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            ax = app.UIAxes;
            t =0:0.01:5*pi;
            if isequal(app.DropDown.Value, 'Sine')
            y = app.AmplitudeKnob.Value*sin(2*pi*app.FrequencyKnob.Value.*t);
            else
              y = app.AmplitudeKnob.Value*square(2*pi*app.FrequencyKnob.Value.*t);
            end
            plot(ax,t,y)
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.Limits = [0 3];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0 50];
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Sine', 'Square'};
            app.DropDown.Value = 'Sine';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end